classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        RunExperimentButton    matlab.ui.control.Button
        HFRatioLabel           matlab.ui.control.Label
        SpectralSlopeLabel     matlab.ui.control.Label
        SpectralCentroidLabel  matlab.ui.control.Label
        StopButton             matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        LoadButton             matlab.ui.control.Button
        BrightnessSlider       matlab.ui.control.Slider
        BrightnessSliderLabel  matlab.ui.control.Label
        HFRatioAxes            matlab.ui.control.UIAxes
        SlopeAxes              matlab.ui.control.UIAxes
        CentroidAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalAudio 
        processedAudio 
        Fs 
        player
        experimentData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
           [file, path] = uigetfile("test.wav");

                if isequal(file, 0)
                    return;
                end

                filename = fullfile(path, file);

                [x, Fs] = audioread(filename);

                if size(x,2) > 1
                    x = mean(x,2);
                end

                app.originalAudio = x;
                app.Fs = Fs;
                app.processedAudio = x;
                app.experimentData = [];
                
                % 计算 FFT
                N = length(x);
                X = fft(x);

                % 只取正频率部分
                f = (0:N-1) * Fs / N;

                % 计算幅度
                magnitude = abs(X);

                % 只显示一半频率
                halfN = floor(N/2);

                f = f(1:halfN);
                magnitude = magnitude(1:halfN);

                % 转换成 dB
                magnitude_dB = 20 * log10(magnitude + eps);

                % 在 UIAxes 中画频谱
                plot(app.CentroidAxes, f, magnitude_dB);

                xlabel(app.CentroidAxes, 'Frequency (Hz)');
                ylabel(app.CentroidAxes, 'Magnitude (dB)');
                title(app.CentroidAxes, 'Frequency Spectrum');
                grid(app.CentroidAxes, 'on');
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if isempty(app.processedAudio)
                return;
            end

            app.player = audioplayer(app.processedAudio, app.Fs);

            play(app.player);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end
        end

        % Value changed function: BrightnessSlider
        function Changed(app, event)
            % 获取滑块数值
            brightness = app.BrightnessSlider.Value;

            % 如果还没有加载音频，就直接返回
            if isempty(app.originalAudio)
                return;
            end

            x = app.originalAudio;
            Fs = app.Fs;

            % FFT
            N = length(x);
            X = fft(x);

            % 创建频率轴
            f = (0:N-1)' * Fs / N;

            % 归一化频率
            normalizedFreq = f / (Fs/2);

            % 防止超过 1
            normalizedFreq(normalizedFreq > 1) = 1;

            % 根据滑块改变高频增益
            gain = 10.^(brightness .* normalizedFreq * 6 / 20);

            % 对频谱进行处理
            Y = X .* gain;

            % 转换回时域
            y = real(ifft(Y));

            % 防止声音超过范围
            maxValue = max(abs(y));

            if maxValue > 1
                y = y / maxValue;
            end

            % 保存处理后的音频
            app.processedAudio = y;

            % 更新频谱显示
            magnitude = abs(Y);

            halfN = floor(N/2);

            f_plot = f(1:halfN);
            magnitude_plot = magnitude(1:halfN);

            magnitude_dB = 20 * log10(magnitude_plot + eps);

            plot(app.CentroidAxes, f_plot, magnitude_dB);

            xlabel(app.CentroidAxes, 'Frequency (Hz)');
            ylabel(app.CentroidAxes, 'Magnitude (dB)');
            title(app.CentroidAxes, 'Frequency Spectrum');
            grid(app.CentroidAxes, 'on');


            % ===== 计算 Spectral Centroid =====
            centroid = sum(f_plot .* magnitude_plot) / ...
                (sum(magnitude_plot) + eps);

            app.SpectralCentroidLabel.Text = ...
                sprintf('Spectral Centroid: %.1f Hz', centroid);


            % ===== 计算 Spectral Slope =====
            spectrum_dB = 20 * log10(magnitude_plot + eps);

            p = polyfit(f_plot, spectrum_dB, 1);

            slope = p(1);

            app.SpectralSlopeLabel.Text = ...
                sprintf('Spectral Slope: %.5f', slope);


            % ===== 计算 HF Ratio =====
            % HF Ratio（高频能量比例）
            highFreqIndex = f_plot >= 4000;

            highFreqEnergy = sum(magnitude_plot(highFreqIndex).^2);
            totalEnergy = sum(magnitude_plot.^2);

            hfRatio = highFreqEnergy / totalEnergy;

            app.HFRatioLabel.Text = ...
                sprintf('HF Ratio: %.6f', hfRatio);

            newRow = [brightness, centroid, slope, hfRatio];

            app.experimentData = [
                app.experimentData;
                newRow
                ];
            
        end

        % Button pushed function: RunExperimentButton
        function Experiment(app, event)
            if isempty(app.originalAudio)
                return;
            end

            % Fixed brightness levels
            levels = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];

            % Clear previous experiment data
            app.experimentData = [];

            for i = 1:length(levels)

                % Set slider value
                brightness = levels(i);
                app.BrightnessSlider.Value = brightness;

                % Get original audio
                x = app.originalAudio;
                Fs = app.Fs;

                % FFT
                N = length(x);
                X = fft(x);

                % Frequency axis
                f = (0:N-1)' * Fs / N;

                % Frequency-dependent gain
                normalizedFreq = f / (Fs/2);
                normalizedFreq(normalizedFreq > 1) = 1;

                gain = 10.^(brightness .* normalizedFreq * 6 / 20);

                % Apply gain
                Y = X .* gain;

                % Inverse FFT
                y = real(ifft(Y));

                % Prevent clipping
                maxValue = max(abs(y));
                if maxValue > 1
                    y = y / maxValue;
                end

                % Spectrum
                magnitude = abs(Y);

                halfN = floor(N/2);

                f_plot = f(1:halfN);
                magnitude_plot = magnitude(1:halfN);

                % Spectral centroid
                centroid = sum(f_plot .* magnitude_plot) / ...
                    (sum(magnitude_plot) + eps);

                % Spectral slope
                spectrum_dB = 20 * log10(magnitude_plot + eps);
                p = polyfit(f_plot, spectrum_dB, 1);
                slope = p(1);

                % HF ratio
                highFreqIndex = f_plot >= 4000;

                highFreqEnergy = sum(magnitude_plot(highFreqIndex).^2);
                totalEnergy = sum(magnitude_plot.^2);

                hfRatio = highFreqEnergy / (totalEnergy + eps);

                % Store result
                newRow = [brightness, centroid, slope, hfRatio];

                app.experimentData = [
                    app.experimentData;
                    newRow
                    ];
                data = app.experimentData;
                save('DSP_reference_results.mat', 'data');

            end

            % Display the result in Command Window
            disp(app.experimentData);
            %% Spectral Centroid
            plot(app.CentroidAxes, ...
                app.experimentData(:,1), ...
                app.experimentData(:,2), ...
                '-o');

            xlabel(app.CentroidAxes, 'Brightness');
            ylabel(app.CentroidAxes, 'Spectral Centroid (Hz)');
            title(app.CentroidAxes, 'Spectral Centroid');
            grid(app.CentroidAxes, 'on');


            %% Spectral Slope
            plot(app.SlopeAxes, ...
                app.experimentData(:,1), ...
                app.experimentData(:,3), ...
                '-o');

            xlabel(app.SlopeAxes, 'Brightness');
            ylabel(app.SlopeAxes, 'Spectral Slope');
            title(app.SlopeAxes, 'Spectral Slope');
            grid(app.SlopeAxes, 'on');


            %% HF Ratio
            plot(app.HFRatioAxes, ...
                app.experimentData(:,1), ...
                app.experimentData(:,4), ...
                '-o');

            xlabel(app.HFRatioAxes, 'Brightness');
            ylabel(app.HFRatioAxes, 'HF Ratio');
            title(app.HFRatioAxes, 'High-Frequency Ratio');
            grid(app.HFRatioAxes, 'on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CentroidAxes = uiaxes(app.UIFigure);
            title(app.CentroidAxes, 'Title')
            xlabel(app.CentroidAxes, 'X')
            ylabel(app.CentroidAxes, 'Y')
            zlabel(app.CentroidAxes, 'Z')
            app.SlopeAxes = uiaxes(app.UIFigure);
            title(app.SlopeAxes, 'Title')
            xlabel(app.SlopeAxes, 'X')
            ylabel(app.SlopeAxes, 'Y')
            zlabel(app.SlopeAxes, 'Z')
            app.HFRatioAxes = uiaxes(app.UIFigure);
            title(app.HFRatioAxes, 'Title')
            xlabel(app.HFRatioAxes, 'X')
            ylabel(app.HFRatioAxes, 'Y')
            zlabel(app.HFRatioAxes, 'Z')
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'BrightnessSlider';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @Changed, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SpectralCentroidLabel = uilabel(app.UIFigure);
            app.SpectralCentroidLabel.Text = 'Spectral Centroid:';
            app.SpectralSlopeLabel = uilabel(app.UIFigure);
            app.SpectralSlopeLabel.Text = 'Spectral Slope:';
            app.HFRatioLabel = uilabel(app.UIFigure);
            app.HFRatioLabel.Text = 'HF Ratio:';
            app.RunExperimentButton = uibutton(app.UIFigure, 'push');
            app.RunExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @Experiment, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end