classdef MusicFeatureLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MainGrid                 matlab.ui.container.GridLayout
        StatusLabel              matlab.ui.control.Label
        Tabs                     matlab.ui.container.TabGroup
        AudioTab                 matlab.ui.container.Tab
        AudioGrid                matlab.ui.container.GridLayout
        PlotGrid                 matlab.ui.container.GridLayout
        TrajectoryAxes           matlab.ui.control.UIAxes
        SpectrogramAxes          matlab.ui.control.UIAxes
        SpectrumAxes             matlab.ui.control.UIAxes
        WaveAxes                 matlab.ui.control.UIAxes
        ControlsPanel            matlab.ui.container.Panel
        ControlsGrid             matlab.ui.container.GridLayout
        FeatureDropDown          matlab.ui.control.DropDown
        MetricsTable             matlab.ui.control.Table
        HelpLabel                matlab.ui.control.Label
        AnalyzeButton            matlab.ui.control.Button
        PitchCheckBox            matlab.ui.control.CheckBox
        ResetButton              matlab.ui.control.Button
        BrightnessValueLabel     matlab.ui.control.Label
        BrightnessSlider         matlab.ui.control.Slider
        BrightnessLabel          matlab.ui.control.Label
        CalibrationTab           matlab.ui.container.Tab
        CalibrationGrid          matlab.ui.container.GridLayout
        CalibrationTable         matlab.ui.control.Table
        CalibrationPlotGrid      matlab.ui.container.GridLayout
        HFAxes                   matlab.ui.control.UIAxes
        SlopeAxes                matlab.ui.control.UIAxes
        CentroidAxes             matlab.ui.control.UIAxes
        CalibrationLabel         matlab.ui.control.Label
        CalibrationToolbar       matlab.ui.container.GridLayout
        CalibrationExportButton  matlab.ui.control.Button
        RunCalibrationButton     matlab.ui.control.Button
        LibraryTab               matlab.ui.container.Tab
        LibraryGrid              matlab.ui.container.GridLayout
        BatchNoteLabel           matlab.ui.control.Label
        BatchTable               matlab.ui.control.Table
        BatchMetricDropDown      matlab.ui.control.DropDown
        LibraryPathLabel         matlab.ui.control.Label
        LibraryToolbar           matlab.ui.container.GridLayout
        CancelBatchButton        matlab.ui.control.Button
        BatchExportButton        matlab.ui.control.Button
        PreviewSelectedButton    matlab.ui.control.Button
        RunBatchButton           matlab.ui.control.Button
        ChooseLibraryButton      matlab.ui.control.Button
        BatchAxes                matlab.ui.control.UIAxes
        SynthTab                 matlab.ui.container.Tab
        SynthGrid                matlab.ui.container.GridLayout
        MethodsText              matlab.ui.control.TextArea
        SynthControls            matlab.ui.container.Panel
        SynthControlGrid         matlab.ui.container.GridLayout
        SynthHelpLabel           matlab.ui.control.Label
        GenerateButton           matlab.ui.control.Button
        DurationField            matlab.ui.control.NumericEditField
        DurationLabel            matlab.ui.control.Label
        IndexField               matlab.ui.control.NumericEditField
        IndexLabel               matlab.ui.control.Label
        ModulatorField           matlab.ui.control.NumericEditField
        ModulatorLabel           matlab.ui.control.Label
        CarrierField             matlab.ui.control.NumericEditField
        CarrierLabel             matlab.ui.control.Label
        SynthAxes                matlab.ui.control.UIAxes
        FileLabel                matlab.ui.control.Label
        Toolbar                  matlab.ui.container.GridLayout
        ExportButton             matlab.ui.control.Button
        SaveAudioButton          matlab.ui.control.Button
        StopButton               matlab.ui.control.Button
        PlayOutputButton         matlab.ui.control.Button
        PlayOriginalButton       matlab.ui.control.Button
        DemoButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        SubtitleLabel            matlab.ui.control.Label
        HeaderLabel              matlab.ui.control.Label
    end

    properties (GetAccess = public, SetAccess = private)
        OriginalAudio = []
        ProcessedAudio = []
        SampleRate = 0
        OriginalResult = struct([])
        ProcessedResult = struct([])
        CalibrationResults = table()
        BatchResults = table()
        SourceName = ''
        SourcePath = ''
        AppFolder = ''
        LibraryFolder = ''
        Brightness = 0
        PeakScale = 1
        AudioPitchEnabled = false
        BatchPitchEnabled = false
    end
    properties (Access = private)
        Player = []
        Busy = false
        CancelRequested = false
        Closing = false
        LastBatchRoot = ''
    end

    methods (Access = public)
        function loadAudio(app, filename)
            % Public workflow also supports reproducible automated checks.
            if app.Busy, return; end
            app.setBusy(true);
            guard=onCleanup(@()app.setBusy(false));
            app.stopAudio();
            app.status('Reading and analyzing audio...'); drawnow;
            info = audioinfo(filename);
            if info.TotalSamples < 1
                error('MusicFeatureLab:Empty', 'The selected file contains no audio samples.');
            end
            [x, fs] = audioread(filename);
            % Compute before committing state so a failed import is harmless.
            r = MusicAnalysis.analyze(x, fs, app.PitchCheckBox.Value);
            app.OriginalAudio = x;
            app.ProcessedAudio = x;
            app.SampleRate = fs;
            app.OriginalResult = r;
            app.ProcessedResult = r;
            app.AudioPitchEnabled = app.PitchCheckBox.Value;
            app.SourcePath = char(filename);
            [~,name,ext] = fileparts(filename);
            app.SourceName = [name ext];
            app.Brightness = 0;
            app.PeakScale = 1;
            app.BrightnessSlider.Value = 0;
            app.CalibrationResults = table();
            app.CalibrationTable.Data = table();
            cla(app.CentroidAxes); cla(app.SlopeAxes); cla(app.HFAxes);
            app.CalibrationLabel.Text = 'Run a sweep on the current source to compare nine brightness levels.';
            app.FileLabel.Text = sprintf('%s  |  %.2f s  |  %g Hz  |  %d channel(s)', ...
                app.SourceName,r.duration,fs,size(x,2));
            app.renderAudio();
            app.enableAudio(true);
            app.status(['Loaded ' app.SourceName '. ' r.note]);
        end

        function applyBrightness(app, value)
            if app.Busy || isempty(app.OriginalAudio), return; end
            app.setBusy(true);
            guard=onCleanup(@()app.setBusy(false));
            app.stopAudio();
            app.status('Applying brightness and updating analysis...'); drawnow;
            [y, info] = MusicAnalysis.brightness(app.OriginalAudio,app.SampleRate,value);
            r = MusicAnalysis.analyze(y,app.SampleRate,app.PitchCheckBox.Value);
            app.ProcessedAudio = y;
            app.ProcessedResult = r;
            app.AudioPitchEnabled = app.PitchCheckBox.Value;
            app.Brightness = value;
            app.PeakScale = info.peakScale;
            app.BrightnessSlider.Value = value;
            app.renderAudio();
            app.status(sprintf('Brightness %+.2f | Nyquist gain %+.1f dB | Peak scale %.3f. %s', ...
                value,info.gainDB,info.peakScale,r.note));
        end

        function results = runCalibration(app)
            results = table();
            if app.Busy || isempty(app.OriginalAudio), return; end
            app.stopAudio(); app.setBusy(true);
            guard = onCleanup(@()app.setBusy(false));
            app.status('Running nine brightness levels...');
            drawnow;
            results = MusicAnalysis.calibration(app.OriginalAudio,app.SampleRate);
            app.CalibrationResults = results;
            app.CalibrationTable.Data = results;
            ax = {app.CentroidAxes,app.SlopeAxes,app.HFAxes};
            vals = {results.Centroid_Hz,results.Slope_dB_Hz,results.HFRatio};
            labels = {'Centroid (Hz)','Slope (dB/Hz)','HF energy ratio'};
            for k = 1:3
                plot(ax{k},results.Brightness,vals{k},'-o','Color',[.12 .49 .68], ...
                    'LineWidth',1.7,'MarkerFaceColor',[.12 .49 .68]);
                xlabel(ax{k},'Brightness'); ylabel(ax{k},labels{k});
                title(ax{k},labels{k}); grid(ax{k},'on'); xlim(ax{k},[-1 1]);
            end
            app.CalibrationLabel.Text = sprintf('%s | nine levels | same source and analysis settings',app.SourceName);
            app.Tabs.SelectedTab = app.CalibrationTab;
            app.status('Calibration complete. Current audition brightness is preserved.');
        end

        function results = runBatch(app, root)
            results = table();
            if app.Busy, return; end
            if nargin < 2, root = app.LibraryFolder; end
            if ~isfolder(root), error('MusicFeatureLab:Folder','Select an existing music library folder.'); end
            [~,folderInfo]=fileattrib(root);
            root=folderInfo.Name;
            prefix=char(root);
            if ~endsWith(prefix,filesep), prefix=[prefix filesep]; end
            files = dir(fullfile(root,'**','*'));
            files = files(~[files.isdir]);
            keep = false(size(files));
            for k=1:numel(files)
                [~,~,ext]=fileparts(files(k).name);
                keep(k)=ismember(lower(ext),{'.wav','.flac','.mp3','.m4a','.ogg','.aif','.aiff','.au'});
            end
            files=files(keep);
            if isempty(files), error('MusicFeatureLab:NoFiles','No supported audio files were found in this folder.'); end
            app.stopAudio(); app.setBusy(true);
            guard=onCleanup(@()app.setBusy(false));
            app.CancelRequested=false;
            app.CancelBatchButton.Enable='on';
            app.LibraryFolder=char(root);
            app.LastBatchRoot=char(root);
            app.BatchPitchEnabled=app.PitchCheckBox.Value;
            app.LibraryPathLabel.Text=char(root);
            rows=cell(numel(files),1);
            count=0;
            for k=1:numel(files)
                drawnow;
                if app.CancelRequested, break; end
                app.status(sprintf('Analyzing %d / %d: %s',k,numel(files),files(k).name));
                filename=fullfile(files(k).folder,files(k).name);
                relative=extractAfter(string(filename),strlength(string(prefix)));
                parts=split(relative,filesep);
                condition="Ungrouped";
                if numel(parts)>1, condition=parts(1); end
                seedText=regexp(files(k).name,'\d+(?=\.[^.]+$)','match','once');
                seed=str2double(seedText);
                try
                    [x,fs]=audioread(filename);
                    r=MusicAnalysis.analyze(x,fs,app.PitchCheckBox.Value);
                    row=MusicAnalysis.featureTable(r,relative);
                    row.Status="OK";
                catch err
                    blank=struct('duration',NaN,'fs',NaN,'channels',NaN, ...
                        'centroid',NaN,'slope',NaN,'bandwidth',NaN,'hfRatio',NaN, ...
                        'rms',NaN,'pitch',NaN,'flux',NaN,'onsetRate',NaN);
                    row=MusicAnalysis.featureTable(blank,relative);
                    row.Status=string(err.message);
                end
                row.Condition=condition;
                row.Seed=seed;
                count=count+1; rows{count}=row;
            end
            if count>0, results=vertcat(rows{1:count}); end
            app.BatchResults=results;
            app.BatchTable.Data=results;
            app.plotBatch();
            app.Tabs.SelectedTab=app.LibraryTab;
            successful=0;
            if ~isempty(results), successful=sum(results.Status=="OK"); end
            app.status(sprintf('Library analysis finished: %d / %d files, %d successful.', ...
                count,numel(files),successful));
        end

        function exportSession(app, folder)
            if app.Busy, return; end
            app.setBusy(true);
            guard=onCleanup(@()app.setBusy(false));
            if ~isfolder(folder), mkdir(folder); end
            if ~isempty(app.OriginalAudio)
                audiowrite(fullfile(folder,'processed.wav'),app.ProcessedAudio,app.SampleRate,'BitsPerSample',24);
                features=[MusicAnalysis.featureTable(app.OriginalResult,"Original"); ...
                    MusicAnalysis.featureTable(app.ProcessedResult,"Processed")];
                writetable(features,fullfile(folder,'audio_features.csv'));
                exportgraphics(app.WaveAxes,fullfile(folder,'waveform.png'),'Resolution',180);
                exportgraphics(app.SpectrumAxes,fullfile(folder,'spectrum.png'),'Resolution',180);
                exportgraphics(app.SpectrogramAxes,fullfile(folder,'spectrogram.png'),'Resolution',180);
                exportgraphics(app.TrajectoryAxes,fullfile(folder,'feature_trajectory.png'),'Resolution',180);
            end
            if ~isempty(app.CalibrationResults)
                writetable(app.CalibrationResults,fullfile(folder,'calibration.csv'));
                exportgraphics(app.CentroidAxes,fullfile(folder,'calibration_centroid.png'),'Resolution',180);
                exportgraphics(app.SlopeAxes,fullfile(folder,'calibration_slope.png'),'Resolution',180);
                exportgraphics(app.HFAxes,fullfile(folder,'calibration_hf_ratio.png'),'Resolution',180);
            end
            summary=table();
            if ~isempty(app.BatchResults)
                writetable(app.BatchResults,fullfile(folder,'library_features.csv'));
                summary=app.groupSummary();
                if ~isempty(summary), writetable(summary,fullfile(folder,'condition_summary.csv')); end
                exportgraphics(app.BatchAxes,fullfile(folder,'library_comparison.png'),'Resolution',180);
            end
            session=struct('SourcePath',app.SourcePath,'Brightness',app.Brightness, ...
                'PeakScale',app.PeakScale,'SampleRate',app.SampleRate, ...
                'AudioIncludePitch',app.AudioPitchEnabled,'BatchIncludePitch',app.BatchPitchEnabled, ...
                'CalibrationIncludePitch',false,'LibraryRoot',app.LastBatchRoot, ...
                'ExportedAt',char(datetime('now','Format','yyyy-MM-dd HH:mm:ss')), ...
                'Analysis','20 ms Hamming; 4 ms hop; amplitude-weighted centroid; HF >= 4000 Hz', ...
                'DSP','Symmetric whole-file FFT tilt; +/-6 dB at Nyquist; clipping protection');
            calibration=app.CalibrationResults;
            batch=app.BatchResults;
            save(fullfile(folder,'session.mat'),'session','calibration','batch','summary');
            fid=fopen(fullfile(folder,'settings.json'),'w','n','UTF-8');
            if fid<0, error('MusicFeatureLab:Export','Cannot write settings.json.'); end
            fileGuard=onCleanup(@()fclose(fid));
            fprintf(fid,'%s',jsonencode(session,'PrettyPrint',true));
            app.status(['Exported session to ' char(folder)]);
        end

        function summary=groupSummary(app)
            summary=table();
            if isempty(app.BatchResults), return; end
            data=app.BatchResults(app.BatchResults.Status=="OK",:);
            if isempty(data), return; end
            groups=unique(data.Condition,'stable');
            metrics={'Centroid_Hz','Slope_dB_Hz','Bandwidth_Hz','HFRatio','RMS','Pitch_Hz','SpectralFlux','OnsetRate_Hz'};
            summary=table(groups,zeros(numel(groups),1),'VariableNames',{'Condition','Count'});
            for m=1:numel(metrics)
                means=nan(numel(groups),1); deviations=means;
                for k=1:numel(groups)
                    values=data.(metrics{m})(data.Condition==groups(k));
                    values=values(isfinite(values));
                    if ~isempty(values), means(k)=mean(values); deviations(k)=std(values); end
                end
                summary.([metrics{m} '_Mean'])=means;
                summary.([metrics{m} '_SD'])=deviations;
            end
            for k=1:numel(groups), summary.Count(k)=sum(data.Condition==groups(k)); end
        end

        function stopAudio(app)
            if ~isempty(app.Player)
                try, stop(app.Player); catch, end
                app.Player=[];
            end
        end
    end

    methods (Access = private)
        function status(app,message)
            if isvalid(app.UIFigure), app.StatusLabel.Text=char(message); end
        end

        function enableAudio(app,enabled)
            controls={app.PlayOriginalButton,app.PlayOutputButton,app.SaveAudioButton, ...
                app.BrightnessSlider,app.ResetButton,app.AnalyzeButton,app.RunCalibrationButton};
            value=matlab.lang.OnOffSwitchState(enabled);
            for k=1:numel(controls), controls{k}.Enable=value; end
        end

        function setBusy(app,value)
            app.Busy=value;
            if app.Closing || ~isvalid(app.UIFigure), return; end
            controls={app.LoadButton,app.DemoButton,app.ExportButton,app.ChooseLibraryButton, ...
                app.RunBatchButton,app.GenerateButton,app.PitchCheckBox,app.PreviewSelectedButton, ...
                app.CalibrationExportButton,app.BatchExportButton,app.BatchMetricDropDown};
            for k=1:numel(controls), controls{k}.Enable=matlab.lang.OnOffSwitchState(~value); end
            app.enableAudio(~value && ~isempty(app.OriginalAudio));
            app.CancelBatchButton.Enable='off';
        end

        function renderAudio(app)
            a=app.OriginalResult; b=app.ProcessedResult;
            app.BrightnessValueLabel.Text=sprintf('%+.2f  |  Nyquist %+.1f dB',app.Brightness,6*app.Brightness);
            metrics={'Centroid (Hz)';'Slope (dB/Hz)';'Bandwidth (Hz)';'HF ratio >=4 kHz'; ...
                'RMS (mono)';'Pitch estimate (Hz)';'Spectral flux';'Onsets / second'};
            fields={'centroid','slope','bandwidth','hfRatio','rms','pitch','flux','onsetRate'};
            before=zeros(8,1); after=before;
            for k=1:8, before(k)=a.(fields{k}); after(k)=b.(fields{k}); end
            app.MetricsTable.Data=table(metrics,before,after,after-before, ...
                'VariableNames',{'Feature','Original','Processed','Change'});
            plot(app.WaveAxes,a.tWave,a.xWave,'Color',[.65 .7 .75]);
            hold(app.WaveAxes,'on');
            plot(app.WaveAxes,b.tWave,b.xWave,'Color',[.06 .48 .67]);
            hold(app.WaveAxes,'off');
            title(app.WaveAxes,'Waveform | mono analysis view');
            xlabel(app.WaveAxes,'Time (s)'); ylabel(app.WaveAxes,'Amplitude');
            xlim(app.WaveAxes,[0 max(b.duration,1/app.SampleRate)]);
            legend(app.WaveAxes,{'Original','Processed'},'Location','northeast');
            grid(app.WaveAxes,'on');
            plot(app.SpectrumAxes,a.fSpectrum,a.spectrumDB,'Color',[.65 .7 .75]);
            hold(app.SpectrumAxes,'on');
            plot(app.SpectrumAxes,b.fSpectrum,b.spectrumDB,'Color',[.06 .48 .67]);
            hold(app.SpectrumAxes,'off');
            title(app.SpectrumAxes,'Single-sided FFT spectrum');
            xlabel(app.SpectrumAxes,'Frequency (Hz)'); ylabel(app.SpectrumAxes,'Amplitude (dBFS)');
            xlim(app.SpectrumAxes,[0 app.SampleRate/2]); grid(app.SpectrumAxes,'on');
            legend(app.SpectrumAxes,{'Original','Processed'},'Location','northeast');
            if numel(b.tFrames)==1
                dt=max(1/app.SampleRate,b.duration/2);
                tt=[b.tFrames-dt/2 b.tFrames+dt/2];
                imageData=[b.stftDB b.stftDB];
            else
                tt=b.tFrames; imageData=b.stftDB;
            end
            imagesc(app.SpectrogramAxes,tt,b.fSTFT,imageData);
            app.SpectrogramAxes.YDir='normal';
            xlabel(app.SpectrogramAxes,'Time (s)'); ylabel(app.SpectrogramAxes,'Frequency (Hz)');
            title(app.SpectrogramAxes,'Processed STFT | dBFS');
            ylim(app.SpectrogramAxes,[0 app.SampleRate/2]);
            xlim(app.SpectrogramAxes,[0 max(b.duration,1/app.SampleRate)]);
            colormap(app.SpectrogramAxes,'turbo');
            peak=max(imageData(:)); clim(app.SpectrogramAxes,[peak-80 max(peak,peak-79)]);
            app.renderTrajectory();
        end

        function renderTrajectory(app)
            if isempty(app.OriginalResult), return; end
            % Onset markers are hidden from legends; reset removes them too
            % when changing to another feature view.
            cla(app.TrajectoryAxes,'reset');
            feature=app.FeatureDropDown.Value;
            names={'Centroid','Slope','Bandwidth','HF ratio','RMS','Spectral flux','Pitch'};
            fields={'centroidSeries','slopeSeries','bandwidthSeries','hfRatioSeries','rmsSeries','fluxSeries','pitchSeries'};
            selected=find(strcmp(names,feature),1);
            field=fields{selected};
            a=app.OriginalResult; b=app.ProcessedResult;
            if strcmp(feature,'Pitch'), ta=a.pitchTimes; tb=b.pitchTimes;
            else, ta=a.tFrames; tb=b.tFrames; end
            plot(app.TrajectoryAxes,ta,a.(field),'Color',[.65 .7 .75]);
            hold(app.TrajectoryAxes,'on');
            plot(app.TrajectoryAxes,tb,b.(field),'Color',[.06 .48 .67]);
            if strcmp(feature,'Spectral flux')
                for t=b.onsetTimes(:)'
                    xline(app.TrajectoryAxes,t,':','Color',[.85 .45 .17],'HandleVisibility','off');
                end
            end
            hold(app.TrajectoryAxes,'off');
            title(app.TrajectoryAxes,[feature ' over time']);
            xlabel(app.TrajectoryAxes,'Time (s)'); grid(app.TrajectoryAxes,'on');
            units={'Hz','dB/Hz','Hz','Ratio','Amplitude','Amplitude change','Hz'};
            ylabel(app.TrajectoryAxes,units{selected});
        end

        function plotBatch(app)
            cla(app.BatchAxes);
            if isempty(app.BatchResults), return; end
            data=app.BatchResults(app.BatchResults.Status=="OK",:);
            if isempty(data), return; end
            metric=app.BatchMetricDropDown.Value;
            summary=app.groupSummary();
            groups=summary.Condition;
            means=summary.([metric '_Mean']); sd=summary.([metric '_SD']);
            colors=lines(numel(groups));
            hold(app.BatchAxes,'on');
            for k=1:numel(groups)
                values=data.(metric)(data.Condition==groups(k));
                offsets=linspace(-.16,.16,numel(values));
                scatter(app.BatchAxes,k+offsets,values,35,colors(k,:),'filled','HandleVisibility','off');
            end
            errorbar(app.BatchAxes,1:numel(groups),means,sd,'kd','LineStyle','none', ...
                'LineWidth',1.7,'MarkerFaceColor','k','DisplayName','Mean +/- SD');
            hold(app.BatchAxes,'off');
            app.BatchAxes.XTick=1:numel(groups); app.BatchAxes.XTickLabel=cellstr(groups);
            xlim(app.BatchAxes,[.5 numel(groups)+.5]);
            title(app.BatchAxes,[strrep(metric,'_',' ') ' | individual files and group means']);
            ylabel(app.BatchAxes,strrep(metric,'_',' ')); grid(app.BatchAxes,'on');
            app.BatchNoteLabel.Text=sprintf('%d files | groups follow subfolder names | dots: files, diamonds: mean +/- SD | no significance test',height(data));
        end

        function playAudio(app,which)
            if app.Busy || isempty(app.OriginalAudio), return; end
            app.stopAudio();
            if strcmp(which,'original'), x=app.OriginalAudio; else, x=app.ProcessedAudio; end
            app.Player=audioplayer(x,app.SampleRate);
            play(app.Player);
            app.status(['Playing ' which ': ' app.SourceName]);
        end

        function reportError(app,err)
            if isvalid(app.UIFigure)
                app.status(['Error: ' err.message]);
                uialert(app.UIFigure,err.message,'Music Feature Lab');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AppFolder=fileparts(mfilename('fullpath'));
            [~,folderName]=fileparts(app.AppFolder);
            if strcmpi(folderName,'source')
                app.AppFolder=fileparts(app.AppFolder);
            end
            addpath(app.AppFolder);
            app.LibraryFolder=fullfile(app.AppFolder,'data','music library');
            app.LibraryPathLabel.Text=app.LibraryFolder;
            app.enableAudio(false);
            demo=fullfile(app.AppFolder,'demo.wav');
            try
                if isfile(demo), app.loadAudio(demo);
                else, app.status('Import audio, or generate an FM demo to begin.'); end
            catch err, app.reportError(err); end
        end

        % Close request function: UIFigure
        function onClose(app, event)
            if app.Busy
                app.CancelRequested=true;
                app.status('Cancel requested. Close again after the current task finishes.');
                return;
            end
            app.stopAudio();
            app.Closing=true;
            delete(app);
        end

        % Button pushed function: LoadButton
        function onLoad(app, event)
            [file,folder]=uigetfile({'*.wav;*.flac;*.mp3;*.m4a;*.ogg;*.aif;*.aiff','Audio files';'*.*','All files'},'Import audio');
            if isequal(file,0), return; end
            try, app.loadAudio(fullfile(folder,file)); catch err, app.reportError(err); end
        end

        % Button pushed function: DemoButton
        function onDemo(app, event)
            try, app.loadAudio(fullfile(app.AppFolder,'demo.wav')); catch err, app.reportError(err); end
        end

        % Button pushed function: PlayOriginalButton
        function onPlayOriginal(app, event)
            try, app.playAudio('original'); catch err, app.reportError(err); end
        end

        % Button pushed function: PlayOutputButton
        function onPlayOutput(app, event)
            try, app.playAudio('processed'); catch err, app.reportError(err); end
        end

        % Button pushed function: StopButton
        function onStop(app, event)
            app.stopAudio(); app.status('Playback stopped.');
        end

        % Button pushed function: SaveAudioButton
        function onSaveAudio(app, event)
            if isempty(app.ProcessedAudio), return; end
            [file,folder]=uiputfile('*.wav','Save processed audio','processed.wav');
            if isequal(file,0), return; end
            try
                audiowrite(fullfile(folder,file),app.ProcessedAudio,app.SampleRate,'BitsPerSample',24);
                app.status(['Saved ' fullfile(folder,file)]);
            catch err, app.reportError(err); end
        end

        % Button pushed function: BatchExportButton, 
        % ...and 2 other components
        function onExport(app, event)
            root=uigetdir(app.AppFolder,'Choose parent folder for a new session export');
            if isequal(root,0), return; end
            folder=fullfile(root,['MusicLab_' char(datetime('now','Format','yyyyMMdd_HHmmss_SSS'))]);
            try, app.exportSession(folder); catch err, app.reportError(err); end
        end

        % Value changed function: BatchMetricDropDown
        function onBatchMetric(app, event)
            try, app.plotBatch(); catch err, app.reportError(err); end
        end

        % Button pushed function: RunCalibrationButton
        function onCalibration(app, event)
            try, app.runCalibration(); catch err, app.reportError(err); end
        end

        % Button pushed function: ChooseLibraryButton
        function onChooseLibrary(app, event)
            root=uigetdir(app.LibraryFolder,'Select a music library folder');
            if isequal(root,0), return; end
            app.LibraryFolder=root; app.LibraryPathLabel.Text=root;
        end

        % Button pushed function: RunBatchButton
        function onBatch(app, event)
            try, app.runBatch(); catch err, app.reportError(err); end
        end

        % Button pushed function: PreviewSelectedButton
        function onPreviewSelected(app, event)
            if isempty(app.BatchResults), return; end
            selection=app.BatchTable.Selection;
            if isempty(selection), app.status('Select a row in the library table first.'); return; end
            row=selection(1,1);
            if row>height(app.BatchResults), return; end
            try
                app.loadAudio(fullfile(app.LastBatchRoot,char(app.BatchResults.Source(row))));
                app.Tabs.SelectedTab=app.AudioTab;
            catch err, app.reportError(err); end
        end

        % Button pushed function: CancelBatchButton
        function onCancelBatch(app, event)
            app.CancelRequested=true;
            app.status('Stopping after the current file...');
        end

        % Value changed function: BrightnessSlider
        function onBrightness(app, event)
            try, app.applyBrightness(app.BrightnessSlider.Value); catch err, app.reportError(err); end
        end

        % Button pushed function: ResetButton
        function onReset(app, event)
            try, app.applyBrightness(0); catch err, app.reportError(err); end
        end

        % Callback function: AnalyzeButton, PitchCheckBox
        function onAnalyze(app, event)
            if app.Busy, return; end
            app.setBusy(true);
            guard=onCleanup(@()app.setBusy(false));
            try
                if isempty(app.OriginalAudio), return; end
                app.OriginalResult=MusicAnalysis.analyze(app.OriginalAudio,app.SampleRate,app.PitchCheckBox.Value);
                app.ProcessedResult=MusicAnalysis.analyze(app.ProcessedAudio,app.SampleRate,app.PitchCheckBox.Value);
                app.AudioPitchEnabled=app.PitchCheckBox.Value;
                app.renderAudio(); app.status(['Analysis refreshed. ' app.ProcessedResult.note]);
            catch err, app.reportError(err); end
        end

        % Value changed function: FeatureDropDown
        function onFeature(app, event)
            try, app.renderTrajectory(); catch err, app.reportError(err); end
        end

        % Button pushed function: GenerateButton
        function onGenerate(app, event)
            if app.Busy, return; end
            try
                fs=44100;
                x=MusicAnalysis.synthesize(app.CarrierField.Value,app.ModulatorField.Value, ...
                    app.IndexField.Value,app.DurationField.Value,fs);
                r=MusicAnalysis.analyze(x,fs,app.PitchCheckBox.Value);
                app.stopAudio();
                app.OriginalAudio=x; app.ProcessedAudio=x; app.SampleRate=fs;
                app.OriginalResult=r; app.ProcessedResult=r;
                app.AudioPitchEnabled=app.PitchCheckBox.Value;
                app.SourceName='FM synthetic tone'; app.SourcePath='';
                app.Brightness=0; app.PeakScale=1; app.BrightnessSlider.Value=0;
                app.CalibrationResults=table(); app.CalibrationTable.Data=table();
                app.CalibrationLabel.Text='Run a sweep on the current source to compare nine brightness levels.';
                cla(app.CentroidAxes); cla(app.SlopeAxes); cla(app.HFAxes);
                app.FileLabel.Text=sprintf('FM tone | %.2f s | 44100 Hz | mono',r.duration);
                plot(app.SynthAxes,r.fSpectrum,r.spectrumDB,'Color',[.06 .48 .67]);
                title(app.SynthAxes,'Generated FM spectrum');
                xlabel(app.SynthAxes,'Frequency (Hz)'); ylabel(app.SynthAxes,'Amplitude (dBFS)');
                xlim(app.SynthAxes,[0 10000]); grid(app.SynthAxes,'on');
                app.enableAudio(true); app.renderAudio();
                app.status('FM tone generated. Use Audio Workbench to audition and change brightness.');
            catch err, app.reportError(err); end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Music Feature Lab | ELEC5305';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @onClose, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.HeaderLabel = uilabel(app.MainGrid);
            app.HeaderLabel.Text = 'MUSIC FEATURE LAB';
            app.SubtitleLabel = uilabel(app.MainGrid);
            app.SubtitleLabel.Text = 'ELEC5305  /  Explore timbre, measure brightness, compare music.';
            app.Toolbar = uigridlayout(app.MainGrid);
            app.LoadButton = uibutton(app.Toolbar, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @onLoad, true);
            app.LoadButton.Text = 'Import audio';
            app.DemoButton = uibutton(app.Toolbar, 'push');
            app.DemoButton.ButtonPushedFcn = createCallbackFcn(app, @onDemo, true);
            app.DemoButton.Text = 'Load demo';
            app.PlayOriginalButton = uibutton(app.Toolbar, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @onPlayOriginal, true);
            app.PlayOriginalButton.Text = 'Play original';
            app.PlayOutputButton = uibutton(app.Toolbar, 'push');
            app.PlayOutputButton.ButtonPushedFcn = createCallbackFcn(app, @onPlayOutput, true);
            app.PlayOutputButton.Text = 'Play processed';
            app.StopButton = uibutton(app.Toolbar, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @onStop, true);
            app.StopButton.Text = 'Stop';
            app.SaveAudioButton = uibutton(app.Toolbar, 'push');
            app.SaveAudioButton.ButtonPushedFcn = createCallbackFcn(app, @onSaveAudio, true);
            app.SaveAudioButton.Text = 'Save WAV';
            app.ExportButton = uibutton(app.Toolbar, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @onExport, true);
            app.ExportButton.Text = 'Export session';
            app.FileLabel = uilabel(app.MainGrid);
            app.FileLabel.Text = 'Import an audio file to begin.';
            app.Tabs = uitabgroup(app.MainGrid);
            app.AudioTab = uitab(app.Tabs);
            app.AudioTab.Title = 'Audio Workbench';
            app.AudioGrid = uigridlayout(app.AudioTab);
            app.ControlsPanel = uipanel(app.AudioGrid);
            app.ControlsPanel.Title = 'Brightness & measurements';
            app.ControlsGrid = uigridlayout(app.ControlsPanel);
            app.BrightnessLabel = uilabel(app.ControlsGrid);
            app.BrightnessLabel.Text = 'Darker                      Neutral                      Brighter';
            app.BrightnessSlider = uislider(app.ControlsGrid);
            app.BrightnessSlider.Limits = [-1 1];
            app.BrightnessSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @onBrightness, true);
            app.BrightnessValueLabel = uilabel(app.ControlsGrid);
            app.BrightnessValueLabel.Text = '+0.00 | Nyquist +0.0 dB';
            app.ResetButton = uibutton(app.ControlsGrid, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @onReset, true);
            app.ResetButton.Text = 'Reset to original (0)';
            app.PitchCheckBox = uicheckbox(app.ControlsGrid);
            app.PitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @onAnalyze, true);
            app.PitchCheckBox.Text = 'Include pitch estimate (Audio Toolbox)';
            app.AnalyzeButton = uibutton(app.ControlsGrid, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @onAnalyze, true);
            app.AnalyzeButton.Text = 'Refresh analysis';
            app.HelpLabel = uilabel(app.ControlsGrid);
            app.HelpLabel.Text = ['Release the slider to process the full clip.' newline 'Stereo is preserved for playback and export.' newline 'Plots and metrics use the mono channel average.' newline 'Clipping protection may lower the output level.'];
            app.MetricsTable = uitable(app.ControlsGrid);
            app.MetricsTable.ColumnName = {'Feature'; 'Original'; 'Processed'; 'Change'};
            app.MetricsTable.RowName = {};
            app.FeatureDropDown = uidropdown(app.ControlsGrid);
            app.FeatureDropDown.Items = {'Centroid', 'Slope', 'Bandwidth', 'HF ratio', 'RMS', 'Spectral flux', 'Pitch'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @onFeature, true);
            app.FeatureDropDown.Value = 'Centroid';
            app.PlotGrid = uigridlayout(app.AudioGrid);
            app.WaveAxes = uiaxes(app.PlotGrid);
            title(app.WaveAxes, 'Waveform')
            app.SpectrumAxes = uiaxes(app.PlotGrid);
            title(app.SpectrumAxes, 'FFT spectrum')
            app.SpectrogramAxes = uiaxes(app.PlotGrid);
            title(app.SpectrogramAxes, 'STFT')
            app.TrajectoryAxes = uiaxes(app.PlotGrid);
            title(app.TrajectoryAxes, 'Feature trajectory')
            app.CalibrationTab = uitab(app.Tabs);
            app.CalibrationTab.Title = 'Brightness Experiment';
            app.CalibrationGrid = uigridlayout(app.CalibrationTab);
            app.CalibrationToolbar = uigridlayout(app.CalibrationGrid);
            app.RunCalibrationButton = uibutton(app.CalibrationToolbar, 'push');
            app.RunCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @onCalibration, true);
            app.RunCalibrationButton.Text = 'Run 9-level brightness experiment';
            app.CalibrationExportButton = uibutton(app.CalibrationToolbar, 'push');
            app.CalibrationExportButton.ButtonPushedFcn = createCallbackFcn(app, @onExport, true);
            app.CalibrationExportButton.Text = 'Export session & figures';
            app.CalibrationLabel = uilabel(app.CalibrationGrid);
            app.CalibrationLabel.Text = 'Load audio, then run a reproducible sweep from -1 to +1.';
            app.CalibrationPlotGrid = uigridlayout(app.CalibrationGrid);
            app.CentroidAxes = uiaxes(app.CalibrationPlotGrid);
            title(app.CentroidAxes, 'Centroid (Hz)')
            app.SlopeAxes = uiaxes(app.CalibrationPlotGrid);
            title(app.SlopeAxes, 'Slope (dB/Hz)')
            app.HFAxes = uiaxes(app.CalibrationPlotGrid);
            title(app.HFAxes, 'HF energy ratio')
            app.CalibrationTable = uitable(app.CalibrationGrid);
            app.CalibrationTable.RowName = {};
            app.LibraryTab = uitab(app.Tabs);
            app.LibraryTab.Title = 'Music Library';
            app.LibraryGrid = uigridlayout(app.LibraryTab);
            app.BatchAxes = uiaxes(app.LibraryGrid);
            title(app.BatchAxes, 'Analyze the library to compare conditions')
            app.LibraryToolbar = uigridlayout(app.LibraryGrid);
            app.ChooseLibraryButton = uibutton(app.LibraryToolbar, 'push');
            app.ChooseLibraryButton.ButtonPushedFcn = createCallbackFcn(app, @onChooseLibrary, true);
            app.ChooseLibraryButton.Text = 'Choose library folder';
            app.RunBatchButton = uibutton(app.LibraryToolbar, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @onBatch, true);
            app.RunBatchButton.Text = 'Analyze library';
            app.PreviewSelectedButton = uibutton(app.LibraryToolbar, 'push');
            app.PreviewSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @onPreviewSelected, true);
            app.PreviewSelectedButton.Text = 'Load selected file';
            app.BatchExportButton = uibutton(app.LibraryToolbar, 'push');
            app.BatchExportButton.ButtonPushedFcn = createCallbackFcn(app, @onExport, true);
            app.BatchExportButton.Text = 'Export session & tables';
            app.CancelBatchButton = uibutton(app.LibraryToolbar, 'push');
            app.CancelBatchButton.ButtonPushedFcn = createCallbackFcn(app, @onCancelBatch, true);
            app.CancelBatchButton.Enable = 'off';
            app.CancelBatchButton.Text = 'Cancel';
            app.LibraryPathLabel = uilabel(app.LibraryGrid);
            app.LibraryPathLabel.Text = 'Choose a library folder.';
            app.BatchMetricDropDown = uidropdown(app.LibraryGrid);
            app.BatchMetricDropDown.Items = {'Centroid (Hz)', 'Slope (dB/Hz)', 'Bandwidth (Hz)', 'HF ratio', 'RMS', 'Pitch (Hz)', 'Spectral flux', 'Onset rate'};
            app.BatchMetricDropDown.ItemsData = {'Centroid_Hz', 'Slope_dB_Hz', 'Bandwidth_Hz', 'HFRatio', 'RMS', 'Pitch_Hz', 'SpectralFlux', 'OnsetRate_Hz'};
            app.BatchMetricDropDown.ValueChangedFcn = createCallbackFcn(app, @onBatchMetric, true);
            app.BatchMetricDropDown.Value = 'Centroid_Hz';
            app.BatchTable = uitable(app.LibraryGrid);
            app.BatchTable.RowName = {};
            app.BatchTable.SelectionType = 'row';
            app.BatchNoteLabel = uilabel(app.LibraryGrid);
            app.BatchNoteLabel.Text = 'Includes existing Dark / Neutral / Bright recordings. Audio is analyzed locally.';
            app.SynthTab = uitab(app.Tabs);
            app.SynthTab.Title = 'FM Synth & Methods';
            app.SynthGrid = uigridlayout(app.SynthTab);
            app.SynthAxes = uiaxes(app.SynthGrid);
            title(app.SynthAxes, 'FM spectrum')
            app.SynthControls = uipanel(app.SynthGrid);
            app.SynthControls.Title = 'Generate a test tone';
            app.SynthControlGrid = uigridlayout(app.SynthControls);
            app.CarrierLabel = uilabel(app.SynthControlGrid);
            app.CarrierLabel.Text = 'Carrier frequency (Hz)';
            app.CarrierField = uieditfield(app.SynthControlGrid, 'numeric');
            app.CarrierField.Limits = [20 4000];
            app.CarrierField.Value = 440;
            app.ModulatorLabel = uilabel(app.SynthControlGrid);
            app.ModulatorLabel.Text = 'Modulator frequency (Hz)';
            app.ModulatorField = uieditfield(app.SynthControlGrid, 'numeric');
            app.ModulatorField.Limits = [1 1000];
            app.ModulatorField.Value = 110;
            app.IndexLabel = uilabel(app.SynthControlGrid);
            app.IndexLabel.Text = 'Modulation index';
            app.IndexField = uieditfield(app.SynthControlGrid, 'numeric');
            app.IndexField.Limits = [0 10];
            app.IndexField.Value = 2;
            app.DurationLabel = uilabel(app.SynthControlGrid);
            app.DurationLabel.Text = 'Duration (seconds)';
            app.DurationField = uieditfield(app.SynthControlGrid, 'numeric');
            app.DurationField.Limits = [0.1 30];
            app.DurationField.Value = 3;
            app.GenerateButton = uibutton(app.SynthControlGrid, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @onGenerate, true);
            app.GenerateButton.Text = 'Generate & load tone';
            app.SynthHelpLabel = uilabel(app.SynthControlGrid);
            app.SynthHelpLabel.Text = 'Create a repeatable source, then use the Audio Workbench to hear and measure brightness changes.';
            app.MethodsText = uitextarea(app.SynthGrid);
            app.MethodsText.Editable = 'off';
            app.MethodsText.Value = {'METHODS & INTERPRETATION'; 'STFT: 20 ms Hamming window, 4 ms hop. Spectral means omit silent frames.'; 'Centroid/bandwidth: amplitude weighted. Slope: dB per Hz. HF ratio: energy at or above 4 kHz.'; 'Brightness: symmetric whole-file FFT tilt, -6 to +6 dB at Nyquist; peak protection prevents clipping.'; 'Pitch: optional Audio Toolbox estimate; a music mixture has no single unambiguous fundamental.'; 'Library: groups follow subfolder names; summaries are descriptive mean and sample SD.'; 'This app analyzes existing music locally. Generative AI is a separate source of recordings.'; 'Export: processed WAV, feature CSVs, figure PNGs, settings JSON and a MAT session.'};
            app.StatusLabel = uilabel(app.MainGrid);
            app.StatusLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MusicFeatureLabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end